classdef mmWaveVitalv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MaxDistEditField              matlab.ui.control.NumericEditField
        MaxDistEditFieldLabel         matlab.ui.control.Label
        MinDistEditField              matlab.ui.control.NumericEditField
        MinDistEditFieldLabel         matlab.ui.control.Label
        AmountofTimesTextArea         matlab.ui.control.TextArea
        AmountofTimesTextAreaLabel    matlab.ui.control.Label
        FramePeriodicitymsEditField   matlab.ui.control.NumericEditField
        NumberofFramesEditFieldLabel  matlab.ui.control.Label
        FramePeriodicitymsEditFieldLabel  matlab.ui.control.Label
        NumberofFramesEditField       matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        FrameIndexForMagDisGraphSpinner  matlab.ui.control.Spinner
        FrameIndexForMagDisGraphSpinnerLabel  matlab.ui.control.Label
        DistOverTimeGraph             matlab.ui.control.UIAxes
        MagnitudeOverDistGraph        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        distancesDetected % Description
        magnitudesOverDistances
        distIntervals
        minDistance
        maxDistance
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        app.minDistance = app.MinDistEditField.Value;
        app.maxDistance = app.MaxDistEditField.Value;
            % Call experiment2.m script
        [app.distancesDetected, app.magnitudesOverDistances,...
            app.distIntervals] = experiment2(app.minDistance, app.maxDistance, app.NumberofFramesEditField.Value, app.FramePeriodicitymsEditField.Value);
        %clearvars -except detectedDistances;
        %app.distancesDetected = detectedDistances;
        %app.magnitudesOverDistances = absFFTOutputs;
        %app.distIntervals = distanceIntervals;
        scale = linspace(0, app.NumberofFramesEditField.Value * app.FramePeriodicitymsEditField.Value * 0.001, app.NumberofFramesEditField.Value);
        %disp(scale);
        %disp(app.distancesDetected);
        plot(app.DistOverTimeGraph, scale, app.distancesDetected);
        plot(app.MagnitudeOverDistGraph, app.distIntervals(:,:,1), app.magnitudesOverDistances(:,:,1));
        xlim(app.MagnitudeOverDistGraph, [app.minDistance,app.maxDistance]);
        % Further operations or assignments as needed
        end

        % Value changed function: FrameIndexForMagDisGraphSpinner
        function FrameIndexForMagDisGraphSpinnerValueChanged(app, event)
            value = app.FrameIndexForMagDisGraphSpinner.Value;
            plot(app.MagnitudeOverDistGraph, app.distIntervals(:,:,value), app.magnitudesOverDistances(:,:,value));
            
        end

        % Value changed function: NumberofFramesEditField
        function NumberofFramesEditFieldValueChanged(app, event)
            value = app.NumberofFramesEditField.Value;
            app.AmountofTimesTextArea.Value = sprintf('%.3f', value * app.FramePeriodicitymsEditField.Value * 0.001);
            app.FrameIndexForMagDisGraphSpinner.Limits = [1,value];
        end

        % Value changed function: FramePeriodicitymsEditField
        function FramePeriodicitymsEditFieldValueChanged(app, event)
            value = app.FramePeriodicitymsEditField.Value;
            app.AmountofTimesTextArea.Value = sprintf('%.3f', value * app.NumberofFramesEditField.Value * 0.001);
        end

        % Value changed function: MinDistEditField
        function MinDistEditFieldValueChanged(app, event)
            app.minDistance = app.MinDistEditField.Value;
        end

        % Value changed function: MaxDistEditField
        function MaxDistEditFieldValueChanged(app, event)
            app.maxDistance = app.MaxDistEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MagnitudeOverDistGraph = uiaxes(app.UIFigure);
            title(app.MagnitudeOverDistGraph, 'Magnitude/Dist')
            xlabel(app.MagnitudeOverDistGraph, 'Distance (m)')
            ylabel(app.MagnitudeOverDistGraph, 'Magnitude')
            zlabel(app.MagnitudeOverDistGraph, 'Z')
            app.DistOverTimeGraph = uiaxes(app.UIFigure);
            title(app.DistOverTimeGraph, 'Dist/Time')
            xlabel(app.DistOverTimeGraph, 'Time (s)')
            ylabel(app.DistOverTimeGraph, 'Distance (m)')
            zlabel(app.DistOverTimeGraph, 'Z')
            app.FrameIndexForMagDisGraphSpinnerLabel = uilabel(app.UIFigure);
            app.FrameIndexForMagDisGraphSpinnerLabel.Text = {'Frame Index'; '(For Mag/Dis Graph)'};
            app.FrameIndexForMagDisGraphSpinner = uispinner(app.UIFigure);
            app.FrameIndexForMagDisGraphSpinner.Limits = [1 10];
            app.FrameIndexForMagDisGraphSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameIndexForMagDisGraphSpinnerValueChanged, true);
            app.FrameIndexForMagDisGraphSpinner.Value = 1;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NumberofFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofFramesEditField.Limits = [0 65535];
            app.NumberofFramesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofFramesEditFieldValueChanged, true);
            app.FramePeriodicitymsEditFieldLabel = uilabel(app.UIFigure);
            app.FramePeriodicitymsEditFieldLabel.Text = 'Frame Periodicity (ms)';
            app.NumberofFramesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.FramePeriodicitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramePeriodicitymsEditField.Limits = [0 1342];
            app.FramePeriodicitymsEditField.ValueChangedFcn = createCallbackFcn(app, @FramePeriodicitymsEditFieldValueChanged, true);
            app.AmountofTimesTextAreaLabel = uilabel(app.UIFigure);
            app.AmountofTimesTextAreaLabel.Text = 'Amount of Time (s)';
            app.AmountofTimesTextArea = uitextarea(app.UIFigure);
            app.AmountofTimesTextArea.Editable = 'off';
            app.MinDistEditFieldLabel = uilabel(app.UIFigure);
            app.MinDistEditFieldLabel.Text = 'Min Dist.';
            app.MinDistEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinDistEditField.Limits = [0.1 Inf];
            app.MinDistEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistEditFieldValueChanged, true);
            app.MinDistEditField.Value = 0.1;
            app.MaxDistEditFieldLabel = uilabel(app.UIFigure);
            app.MaxDistEditFieldLabel.Text = 'Max Dist.';
            app.MaxDistEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxDistEditField.Limits = [0.1 Inf];
            app.MaxDistEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDistEditFieldValueChanged, true);
            app.MaxDistEditField.Value = 0.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mmWaveVitalv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end